classdef PowerSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        InclinedegSliderLabel  matlab.ui.control.Label
        InclinedegSlider       matlab.ui.control.Slider
        HeadWindmsSliderLabel  matlab.ui.control.Label
        HeadWindmsSlider       matlab.ui.control.Slider
        RunSimButton           matlab.ui.control.Button
        RightPanel             matlab.ui.container.Panel
        TabGroup               matlab.ui.container.TabGroup
        DynamicsTab            matlab.ui.container.Tab
        UIAxes                 matlab.ui.control.UIAxes
        CurrentTab             matlab.ui.container.Tab
        UIAxes_2               matlab.ui.control.UIAxes
        PowerTab               matlab.ui.container.Tab
        UIAxes_3               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Time
        Velocity
        Current
        Power
    end
    
    methods (Access = private)
        
        function simulate(app)
            simOut = sim("sixWheelModel.slx",'CaptureErrors','on');
            app.Time = simOut.get('tout');
            app.Velocity = simOut.get('yout').get('Velocity').Values.Data;
            app.Current = simOut.get('yout').get('Current').Values.Data;
            app.Power = simOut.get('yout').get('Power').Values.Data;
        end
        
        function resetGraph(app)
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            plot(app.UIAxes,app.Time,app.Velocity);
            plot(app.UIAxes_2,app.Time,app.Current);
            plot(app.UIAxes_3,app.Time,app.Power);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InclinedegSlider
        function InclinedegSliderValueChanged(app, event)
            value = app.InclinedegSlider.Value;
            assignin('base','angle',value);
        end

        % Value changed function: HeadWindmsSlider
        function HeadWindmsSliderValueChanged(app, event)
            value = app.HeadWindmsSlider.Value;
            assignin('base','windSpeed',value);
        end

        % Button pushed function: RunSimButton
        function RunSimButtonPushed(app, event)
            app.simulate()
            app.resetGraph()
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {186, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InclinedegSliderLabel = uilabel(app.LeftPanel);
            app.InclinedegSliderLabel.Text = 'Incline (deg)';
            app.InclinedegSlider = uislider(app.LeftPanel);
            app.InclinedegSlider.Limits = [0 40];
            app.InclinedegSlider.MajorTicks = [0 10 20 30 40];
            app.InclinedegSlider.ValueChangedFcn = createCallbackFcn(app, @InclinedegSliderValueChanged, true);
            app.InclinedegSlider.MinorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40];
            app.HeadWindmsSliderLabel = uilabel(app.LeftPanel);
            app.HeadWindmsSliderLabel.Text = 'Head Wind (m/s)';
            app.HeadWindmsSlider = uislider(app.LeftPanel);
            app.HeadWindmsSlider.Limits = [0 20];
            app.HeadWindmsSlider.MajorTicks = [0 5 10 15 20];
            app.HeadWindmsSlider.ValueChangedFcn = createCallbackFcn(app, @HeadWindmsSliderValueChanged, true);
            app.HeadWindmsSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.RunSimButton = uibutton(app.LeftPanel, 'push');
            app.RunSimButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimButtonPushed, true);
            app.RunSimButton.Text = 'Run Sim';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.DynamicsTab = uitab(app.TabGroup);
            app.DynamicsTab.Title = 'Dynamics';
            app.UIAxes = uiaxes(app.DynamicsTab);
            title(app.UIAxes, 'Dynamics')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Velocity(m/s)')
            zlabel(app.UIAxes, 'Z')
            app.CurrentTab = uitab(app.TabGroup);
            app.CurrentTab.Title = 'Current';
            app.UIAxes_2 = uiaxes(app.CurrentTab);
            title(app.UIAxes_2, 'Current')
            xlabel(app.UIAxes_2, 'Time(s)')
            ylabel(app.UIAxes_2, 'Current(A)')
            zlabel(app.UIAxes_2, 'Z')
            app.PowerTab = uitab(app.TabGroup);
            app.PowerTab.Title = 'Power';
            app.UIAxes_3 = uiaxes(app.PowerTab);
            title(app.UIAxes_3, 'Power')
            xlabel(app.UIAxes_3, 'Time(s)')
            ylabel(app.UIAxes_3, 'Power(W)')
            zlabel(app.UIAxes_3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PowerSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end